classdef Crawler_Control_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label_5          matlab.ui.control.Label
        TabGroup         matlab.ui.container.TabGroup
        Tab              matlab.ui.container.Tab
        TextArea         matlab.ui.control.TextArea
        Tab_2            matlab.ui.container.Tab
        TextArea_4       matlab.ui.control.TextArea
        msLabel          matlab.ui.control.Label
        msGauge          matlab.ui.control.SemicircularGauge
        ButtonGroup      matlab.ui.container.ButtonGroup
        Button_10        matlab.ui.control.ToggleButton
        Button_9         matlab.ui.control.ToggleButton
        Button_8         matlab.ui.control.ToggleButton
        Button_7         matlab.ui.control.Button
        Gauge            matlab.ui.control.Gauge
        Label_3          matlab.ui.control.Label
        Button_6         matlab.ui.control.Button
        Panel_2          matlab.ui.container.Panel
        TextArea_3       matlab.ui.control.TextArea
        Panel            matlab.ui.container.Panel
        TextArea_2       matlab.ui.control.TextArea
        Button_5         matlab.ui.control.Button
        Button_4         matlab.ui.control.StateButton
        Button_3         matlab.ui.control.Button
        DropDown         matlab.ui.control.DropDown
        Label_2          matlab.ui.control.Label
        Lamp             matlab.ui.control.Lamp
        Button_2         matlab.ui.control.StateButton
        DropDown_2       matlab.ui.control.DropDown
        DropDown_2Label  matlab.ui.control.Label
        Switch           matlab.ui.control.Switch
        Label            matlab.ui.control.Label
    end

    
    properties (Access = private)
        Timer_1; % 计时器1，用来更新COM口
        Timer_2; % 计时器2，用来管理收发
        Timer_3; % 用来检查Serial接口是否产生变化
        current_com; %当前选择的COM口
        Serial;
        Serial_Link_FLAG = false; %连接串口成功标志
        receiveData=[];
        Control_statue;%处于遥控器控制还是程序自动控制
        CurrentPosition;
        PreviousPosition;
        Battery; %电池电量
        Velocity; % Description
        Address=2; % 目标地址，如果换车的话就在这里修改
        Channel=4; %频率信道
        CarNumber='C2'; %车号,发送的指令需要有车号来表示是谁发送的
    end
    methods(Access=private)

        function updateSerialList(app)
            %把可用COM口添加到下拉列表里
            COM_PORTs = serialportlist;
            app.DropDown_2.Items = COM_PORTs;
        end
        function receiveDataFromSerialPort(app,~,~)
            % 从串口接收数据
            if app.Serial.NumBytesAvailable>0
            app.receiveData = read(app.Serial,app.Serial.NumBytesAvailable,'char');%数据转化为ASCII码
            ReceiveWrite(app,app.receiveData);
            end
        end
        function sendDataToSerialPort(app, data)
            % 向串口发送数据
            wrtieline(app.Serial, data);
        end
        function statue = SendCommand(app,command,expectedResponse)
            expectedResponse = strcat(app.CarNumber,expectedResponse);
            %定点传输的地址,地址为2，频率信道为4 
            %帧头里
            Command_Header = sprintf('%04X%02X', app.Address, app.Channel);
            Command_Header = char(reshape(sscanf(Command_Header, '%2X'), 1, []));
            command = strcat(Command_Header,command);
            for i = 1:3  % 最多重复3次
                % 发送指令到串口
                writeline(app.Serial, command);
                pause(0.3) %延时100ms，给单片机反应时间
                % 设置超时时间（秒）
                timeout = 0.3;  % 60毫秒延迟        
                % 开始计时
                startTime = tic;
                % 等待串口返回消息
                while toc(startTime) < timeout
                        % 检查是否收到期望的回复消息
                    if app.Serial.NumBytesAvailable >0||~isempty(app.receiveData) %只要有可以读取的信息或接收到信息不为空
                        if app.Serial.NumBytesAvailable >0 
                            app.receiveDataFromSerialPort(app) %如果有信息没有读取，则读取信息
                        end
                        if contains(app.receiveData,expectedResponse)%收到的信息中有没有期望的回复
                            %已经成功收到答复
                            statue = true; %成功发送了指令，状态为1
                            return;
                        elseif length(app.receiveData)>strlength(expectedResponse) %如果收到的信息数大于预期，说明可能被包含
                            if strcmp(app.receiveData(1:strlength(expectedResponse)),expectedResponse)
                               statue = true; %成功发送了指令，状态为1
                               return;
                            end
                        end
                    end
                end
                statue = false;  %默认失败，如果成功会更改值
            end
            

        end
        function State =  UpdateInformation(app) %接收履带车信息
            State.Node = false;
            State.Battery = false;
            if app.Serial_Link_FLAG ~= true
                State.Node = false;
                State.Battery = false;
                return;
            end
            
            if app.Serial.NumBytesAvailable>0
                app.receiveDataFromSerialPort(app);%数据转化为ASCII码
            end
            
            PositionBuffer = app.receiveData;
            if isempty(PositionBuffer)
                State.Node = false;
                State.Battery = false;
                return;
            end
            if length(PositionBuffer)>=25 
                if contains(PositionBuffer,[app.CarNumber,'There are obstacles ahead'])  %顺便把障碍检测给做了
                    text_write(app,"履带车遇到障碍")
                    PositionBuffer = PositionBuffer(26:end); %这部分原来是PositionBuffer=""
                    app.receiveData = app.receiveData(26:end);
                end
            end
            if length(PositionBuffer)>=10
                if contains(PositionBuffer,[app.CarNumber,'Battery '])%电池电量检测
                    BatteryIndex = strfind(PositionBuffer,[app.CarNumber,'Battery ']);
                    app.Battery = str2double(PositionBuffer(BatteryIndex(end)+8+strlength(app.CarNumber):BatteryIndex(end)+9+strlength(app.CarNumber)));
                    BatteryReport(app);
                    State.Battery = true;
                end
            end
            if length(PositionBuffer)>=9  %速度检测
                if contains(PositionBuffer,[app.CarNumber,'Vel '])
                    VelIndex = strfind(PositionBuffer,[app.CarNumber,'Vel ']);
                    app.Velocity = str2double(PositionBuffer(VelIndex(end)+4+strlength(app.CarNumber):VelIndex(end)+8+strlength(app.CarNumber)));
                    app.msGauge.Value = app.Velocity;
                    app.msLabel.Text = [num2str(app.Velocity),' m/s'];


                end
            end
            if length(PositionBuffer)>=7 %收到的信息是否够长
                if contains(PositionBuffer,[app.CarNumber,'Pos ']) %如果收到的是位置更新的帧头  指令格式为Pos 当前位置 之前位置
                    PositionIndex = strfind(PositionBuffer,[app.CarNumber,'Pos ']);
                app.CurrentPosition = PositionBuffer(PositionIndex(end)+4+strlength(app.CarNumber));   %读取当前位置
                app.PreviousPosition = PositionBuffer(PositionIndex(end)+6+strlength(app.CarNumber));  %读取之前位置
            
                switch app.CurrentPosition
                    case '1'
                        app.TextArea_2.Value = "泊车点";
                    case '2'
                         app.TextArea_2.Value = "停车点A";
                    case '3'
                         app.TextArea_2.Value = "停车点B";
                    case '4'
                         app.TextArea_2.Value = "分岔路A";
                    case '5'
                         app.TextArea_2.Value = "分岔路B";
                    otherwise
                         text_write(app,"当前位置更新出现异常值");
                end
                switch app.PreviousPosition
                    case '1'
                        app.TextArea_3.Value = "泊车点";
                    case '2'
                         app.TextArea_3.Value = "停车点A";
                    case '3'
                         app.TextArea_3.Value = "停车点B";
                    case '4'
                         app.TextArea_3.Value = "分岔路A";
                    case '5'
                         app.TextArea_3.Value = "分岔路B";
                    otherwise
                         text_write(app,"之前位置更新出现异常值");
                end
                State.Node = true;
                app.receiveData = app.receiveData(8:end);
                 end
            end
        end
        function text_write(app,text)
            %用来向界面写入文字
            if isempty(app.TextArea.Value) %如果是空的就直接赋值
                app.TextArea.Value = text;
            else %不是就往后写
                app.TextArea.Value = [app.TextArea.Value;text];
            end
        end
        function CloseCom(app)
            %用来关闭串口
            global LoRaSerial;
            delete(app.Serial);
            if ~isempty(LoRaSerial)
                
                delete(LoRaSerial);
            end

        end
        
        function BatteryReport(app)
            %用来展现接收到的电池电量信息
            app.Gauge.Value = app.Battery;
            app.Button_7.Text = [num2str(app.Battery),'%'];
        end
        
        function ReceiveWrite(app,text)   %直接查看接收信息
            if isempty(app.TextArea_4.Value) %如果是空的就直接赋值
                app.TextArea_4.Value = text;
            else %不是就往后写
                app.TextArea_4.Value = [app.TextArea_4.Value;text];
            end
        end
        
        function SerialChangedFcn(app)
            global LoRaSerial
            
            app.Serial = LoRaSerial;
            if(isvalid(app.Serial)) %如果有效，就是连接了串口的
                app.Button_2.Value = 1; %激活按钮
                app.Lamp.Color = [0.00,1.00,0.00];
                app.Lamp.Enable = "on";
                app.Serial_Link_FLAG = true;
                if ismember(app.Serial.Port,app.DropDown_2.Items)
                    app.DropDown_2.Value = app.Serial.Port;
                end
            else %如果串口已经关闭
                app.Lamp.Color = [1.00,0.00,0.00];
                app.Lamp.Enable = "off";
                app.Serial_Link_FLAG = false;
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %不断读取可用的COM口
            global LoRaSerial;
            
            app.Timer_1 = timer("ExecutionMode","fixedRate",'Period', ...
                2,'TimerFcn',@(src, event)updateSerialList(app));
            start(app.Timer_1);
            app.Timer_2 = timer("ExecutionMode","fixedSpacing",'Period', ...
                0.1,'TimerFcn', @(~,~) UpdateInformation(app));  %随时准备接收位置信息
            start(app.Timer_2);
            app.Timer_3 = timer("ExecutionMode","fixedRate",'Period', ...
                2,'TimerFcn',@(src, event)SerialChangedFcn(app));
            start(app.Timer_3);
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.Button_2.Value = 0;
            app.Lamp.Color = [1.00,0.00,0.00];
            app.Lamp.Enable = "off";
            CloseCom(app);  %切换串口时关闭原来串口

            
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            Button2_value = app.Button_2.Value;
                global LoRaSerial;
                if Button2_value == 1 %按钮激活
                    %打开串口
                   if ~isempty(app.DropDown_2.Items) %检测是否为无可用COM口
                    try
                        app.Serial = serialport(app.DropDown_2.Value, 9600, "DataBits", 8, "StopBits", 1);
                        configureTerminator(app.Serial, "LF");  %终止符设置为换行符
                        %configureCallback(app.Serial,'byte',1,@app.receiveDataFromSerialPort); %收到信息时立即调用接收信息函数
                        fopen(app.Serial);
                    catch ME
                        text_write(app,ME.message); %报告错误
                        app.Button_2.Value = 0;      %返回之前未开启状态
                        return;                     %退出
                    end
                   end
                    try
                        if strcmp(app.Serial.Status,'open') %串口打开成功
                            LoRaSerial = app.Serial; %将串口传递给全局串口
                            app.Lamp.Color = [0.00,1.00,0.00];
                            app.Lamp.Enable = "on";
                            text_write(app,"串口打开成功");
                            app.Serial_Link_FLAG = true;  %串口连接中
                        else  %串口打开失败
                            app.Lamp.Color = [1.00,0.00,0.00];
                            app.Lamp.Enable = "off";
                            app.Serial_Link_FLAG = false;%串口已断开
                            %这里报告串口打开失败
                            text_write(app,"串口打开失败");
                        end
                    catch ME
                        app.Lamp.Color = [1.00,0.00,0.00];
                        app.Lamp.Enable = "off";
                        %这里打印错误报告
                        text_write(app,"串口打开失败");
                        text_write(app,ME.message);

                        app.Serial_Link_FLAG = false;    %串口已断开
                    end
                elseif Button2_value == 0 %按钮关闭
                    %关闭串口
                    app.Lamp.Color = [1.00,0.00,0.00];
                    app.Lamp.Enable = "off";
                    CloseCom(app);
                    text_write(app,"串口已关闭");
                    app.Serial_Link_FLAG = false;       %串口已断开
                    LoRaSerial = app.Serial; %将关闭的串口传递出去
                end
             
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
           if app.Serial_Link_FLAG == false
               text_write(app,"没有可用串口");
               return;
           end
           if app.Switch.Value == "On"
            if app.DropDown.Value == "泊车点"
                command = "Go to A";
                expectedResponse = "Gopark";       
                Statue = SendCommand(app,command,expectedResponse);
                if Statue
                        text_write(app,"正在前往泊车点");
                    else
                         text_write(app,"履带车无应答 未能成功设置目的地"); 
                         app.Switch.Value = 'Off'; %把开关状态改回Off
                end
            elseif app.DropDown.Value == "停车点A"
                command = "Go to B";
                expectedResponse = "GoStop1";         %%%这里可能要改成16进制
                Statue = SendCommand(app,command,expectedResponse);
                if Statue
                        text_write(app,"正在前往停车点A");
                    else
                         text_write(app,"履带车无应答 未能成功设置目的地"); 
                         app.Switch.Value = 'Off'; %把开关状态改回Off
                end
            elseif app.DropDown.Value == "停车点B"
                command = "Go to C";
                expectedResponse = "Gostop2";         %%%这里可能要改成16进制
                Statue = SendCommand(app,command,expectedResponse);
                if Statue
                        text_write(app,"正在前往停车点B");
                    else
                         text_write(app,"履带车无应答 未能成功设置目的地"); 
                         app.Switch.Value = 'Off'; %把开关状态改回Off
                end
            end
           end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.Control_statue = app.Switch.Value;
            if app.Serial_Link_FLAG == true
                if app.Control_statue == "On"
                    command = "87654321";
                    expectedResponse = "SelfControl";     
                    Statue = SendCommand(app,command,expectedResponse);
                    if Statue
                        text_write(app,"成功进入自动控制模式");
                    else
                         text_write(app,"履带车无应答 未能成功进入自动控制模式"); 
                    end
                    
                %进入遥控控制程序    
                elseif app.Control_statue == "Off"
                    command = "12345678";
                    expectedResponse = "RemoteControl";   
                    Statue = SendCommand(app,command,expectedResponse);
                     if Statue
                        text_write(app,"成功进入遥控控制模式");
                    else
                         text_write(app,"履带车无应答 未能成功进入遥控控制模式"); 
                     end
                    
                end
            else %如果没有可以用的串口
                text_write(app,"没有可用串口");
                if app.Switch.Value == "On"        %把开关拨回去
                    app.Switch.Value = 'Off';
                else
                    app.Switch.Value = 'On';
                end
            end    
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %关闭程序时暂且先只关闭串口，有什么要求可以再改
            CloseCom(app);
            delete(app)
            
        end

        % Drop down opening function: DropDown_2
        function DropDown_2Opening(app, event)
            
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            %清空文本显示
            if app.TabGroup.SelectedTab == app.Tab
                app.TextArea.Value = '';
            else
                app.TextArea_4.Value = '';
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.Serial_Link_FLAG %如果串口连接
                %帧头，两个字节存储LoRa模块地址 1个字节存储频率信道，转化为字符换作为帧头发送
                Command_Header = sprintf('%04X%02X', app.Address, app.Channel);
                Command_Header = char(reshape(sscanf(Command_Header, '%2X'), 1, []));
                Location_Command = strcat(Command_Header,"Report");
                writeline(app.Serial,Location_Command)  %发送报告指令
                pause(0.7)
                State = UpdateInformation(app);
                if State.Node == true
                    text_write(app,"已更新目的地")
                    return
                end
                text_write(app,"未收到位置信息")
            else
                text_write(app,"没有可用串口")
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if app.Serial_Link_FLAG == false
               text_write(app,"没有可用串口");
               return;
            end
            if app.DropDown.Value == "泊车点"
                command = "Set A";
                expectedResponse = "SetPark";       
                Statue = SendCommand(app,command,expectedResponse);
                if Statue
                        text_write(app,"已设置位置为泊车点");
                    else
                         text_write(app,"履带车无应答 未能成功设置目的地"); 
                         app.Switch.Value = 'Off'; %把开关状态改回Off
                end
            elseif app.DropDown.Value == "停车点A"
                command = "Set B";
                expectedResponse = "SetStop1";         %%%这里可能要改成16进制
                Statue = SendCommand(app,command,expectedResponse);
                if Statue
                        text_write(app,"已设置位置为停车点A");
                    else
                         text_write(app,"履带车无应答 未能成功设置目的地"); 
                         app.Switch.Value = 'Off'; %把开关状态改回Off
                end
            elseif app.DropDown.Value == "停车点B"
                command = "Set C";
                expectedResponse = "Setstop2";         %%%这里可能要改成16进制
                Statue = SendCommand(app,command,expectedResponse);
                if Statue
                        text_write(app,"已设置位置为停车点B");
                    else
                         text_write(app,"履带车无应答 未能成功设置目的地"); 
                         app.Switch.Value = 'Off'; %把开关状态改回Off
                end
            end
           
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             if app.Serial_Link_FLAG %如果串口连接
                Command_Header = sprintf('%04X%02X', app.Address, app.Channel);
                Command_Header = char(reshape(sscanf(Command_Header, '%2X'), 1, []));
                Battery_Commnad = strcat(Command_Header,"BatteryReport");
                writeline(app.Serial,Battery_Commnad)  %发送报告指令
                pause(0.7)
                State = UpdateInformation(app);
                if State.Battery ~= true
                    text_write(app,"未收到电池信息")
                else
                    text_write(app,"已更新电池信息")
                end
            else
                text_write(app,"没有可用串口")
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.Serial_Link_FLAG == true
                switch selectedButton.Text
                    case '高速'
                        command = "HighSpeed";
                        expectedResponse = "Shift to HighSpeed";     
                        Statue = SendCommand(app,command,expectedResponse);
                        if Statue
                            text_write(app,"高速行驶过变速路段");
                        else
                             text_write(app,"设置速度失败"); 
                        end
                    case '普通'
                        command = "LowSpeed";
                        expectedResponse = "Shift to MidSpeed";     
                        Statue = SendCommand(app,command,expectedResponse);
                        if Statue
                            text_write(app,"普通速度行驶过变速路段");
                        else
                             text_write(app,"设置速度失败"); 
                        end
                    case '低速'
                        command = "SlowSpeed";
                        expectedResponse = "Shift to SlowSpeed";     
                        Statue = SendCommand(app,command,expectedResponse);
                        if Statue
                            text_write(app,"低速行驶过变速路段");
                        else
                             text_write(app,"设置速度失败"); 
                        end
                    
                end
            else %如果没有可以用的串口
                text_write(app,"没有可用串口");
            end    


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '自动控制';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '可用串口';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_2Opening, true);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '打开串口';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '目的地';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'泊车点', '停车点A', '停车点B'};
            app.DropDown.Value = '泊车点';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '出发';
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '清空页面';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '更新位置';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '下一个目的地';
            app.TextArea_2 = uitextarea(app.Panel);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '即将前往';
            app.TextArea_3 = uitextarea(app.Panel_2);
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '设置';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '剩余电量';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '??%';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '速度挡位';
            app.Button_8 = uitogglebutton(app.ButtonGroup);
            app.Button_8.Text = '普通';
            app.Button_8.Value = true;
            app.Button_9 = uitogglebutton(app.ButtonGroup);
            app.Button_9.Text = '高速';
            app.Button_10 = uitogglebutton(app.ButtonGroup);
            app.Button_10.Text = '低速';
            app.msGauge = uigauge(app.UIFigure, 'semicircular');
            app.msGauge.Limits = [0 0.6];
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = '?? m/s';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '运行日志';
            app.TextArea = uitextarea(app.Tab);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '接收信息';
            app.TextArea_4 = uitextarea(app.Tab_2);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '2号车';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crawler_Control_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end